classdef reconstruction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BrowseButton             matlab.ui.control.Button
        FolderEditFieldLabel     matlab.ui.control.Label
        FolderEditField          matlab.ui.control.EditField
        NoiseMeanEditFieldLabel  matlab.ui.control.Label
        NoiseMeanEditField       matlab.ui.control.NumericEditField
        StdEditFieldLabel        matlab.ui.control.Label
        StdEditField             matlab.ui.control.NumericEditField
        RunButton                matlab.ui.control.Button
        FinishLampLabel          matlab.ui.control.Label
        FinishLamp               matlab.ui.control.Lamp
        DLfolderEditFieldLabel   matlab.ui.control.Label
        DLfolderEditField        matlab.ui.control.EditField
        BrowseButton_2           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            main_path = fullfile(pwd, 'main');
            addpath(genpath(main_path))
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            temp = uigetdir();
            if temp ~= 0
                app.FolderEditField.Value = temp;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.FinishLamp.Color = [0.50,0.50,0.50];
            
            if ~isfolder(app.FolderEditField.Value)
                warndlg('Please choose sorurse floder','warning');
                return
            end
                
            clc
            
            config = struct();
            config.root = app.FolderEditField.Value;
            config.mean = app.NoiseMeanEditField.Value;
            config.std = app.StdEditField.Value;
            config.elastix_path = fullfile(pwd,'main','elastix');
            config.dl_path = app.FolderEditField_2.Value;
            
            configure(config);
            
            app.FinishLamp.Color = [0.00,1.00,0.00];
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            temp = uigetdir();
            if temp ~= 0
                app.DLfolderEditField.Value = temp;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.NoiseMeanEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseMeanEditFieldLabel.Text = 'Noise:Mean';
            app.NoiseMeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseMeanEditField.Value = 100;
            app.StdEditFieldLabel = uilabel(app.UIFigure);
            app.StdEditFieldLabel.Text = 'Std';
            app.StdEditField = uieditfield(app.UIFigure, 'numeric');
            app.StdEditField.Value = 3;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FinishLampLabel = uilabel(app.UIFigure);
            app.FinishLampLabel.Text = 'Finish';
            app.FinishLamp = uilamp(app.UIFigure);
            app.DLfolderEditFieldLabel = uilabel(app.UIFigure);
            app.DLfolderEditFieldLabel.Text = 'DL folder';
            app.DLfolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reconstruction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end